classdef TelescopeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploreTheGalaxyUIFigure        matlab.ui.Figure
        FindObjectButton                matlab.ui.control.Button
        ConfirmLocationButton           matlab.ui.control.Button
        MountAnglesDisplayPanel         matlab.ui.container.Panel
        AltazimuthMovementLabel         matlab.ui.control.Label
        AzimuthEditFieldLabel           matlab.ui.control.Label
        AzimuthEditField                matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        EquatorialMovementLabel         matlab.ui.control.Label
        RightAscensionEditFieldLabel    matlab.ui.control.Label
        RightAscensionEditField         matlab.ui.control.NumericEditField
        DeclinationEditFieldLabel       matlab.ui.control.Label
        DeclinationEditField            matlab.ui.control.NumericEditField
        InputyourcurrentlocationPanel   matlab.ui.container.Panel
        LatitudeLabel                   matlab.ui.control.Label
        LatitudeDegreesEditFieldLabel   matlab.ui.control.Label
        LatitudeDegreesEditField        matlab.ui.control.NumericEditField
        LatitudeMinutesEditFieldLabel   matlab.ui.control.Label
        LatitudeMinutesEditField        matlab.ui.control.NumericEditField
        LatitudeDirectionDropDownLabel  matlab.ui.control.Label
        LatitudeDirectionDropDown       matlab.ui.control.DropDown
        LatitudeSecondsEditFieldLabel   matlab.ui.control.Label
        LatitudeSecondsEditField        matlab.ui.control.NumericEditField
        LongitudeLabel                  matlab.ui.control.Label
        LongitudeDegreesEditFieldLabel  matlab.ui.control.Label
        LongitudeDegreesEditField       matlab.ui.control.NumericEditField
        LongitudeMinutesEditFieldLabel  matlab.ui.control.Label
        LongitudeMinutesEditField       matlab.ui.control.NumericEditField
        LongitudeSecondsEditFieldLabel  matlab.ui.control.Label
        LongitudeSecondsEditField       matlab.ui.control.NumericEditField
        LongitudeDirectionDropDownLabel  matlab.ui.control.Label
        LongitudeDirectionDropDown      matlab.ui.control.DropDown
        GMTEditFieldLabel               matlab.ui.control.Label
        GMTEditField                    matlab.ui.control.NumericEditField
        CelestialObjectFinderLabel      matlab.ui.control.Label
        RecalibrateButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: FindObjectButton
        function FindObjectButtonPushed(app, event)
            %----------------UserInput------------------------- 
            %-----------------PositionOfSystem------------------
            %--Latitude--
            LAT_deg = app.LatitudeDegreesEditField.Value;
            LAT_min = app.LatitudeMinutesEditField.Value;
            LAT_sec = app.LatitudeSecondsEditField.Value;
            LAT_dir = app.LatitudeDirectionDropDown.Value; %north or south
            LAT_pol =0;
            if(strcmp(LAT_dir,'North'))
                LAT_pol = 1;
            end
            if(strcmp(LAT_dir, 'South'))
                LAT_pol=-1;
            end
            %User defined function
            LAT = Latitude(LAT_deg,LAT_min,LAT_sec,LAT_pol);%conversion to degrees            
            
            %--Lonigitude--
            Long_deg = app.LongitudeDegreesEditField.Value;
            Long_min = app.LongitudeMinutesEditField.Value;
            Long_sec = app.LongitudeSecondsEditField.Value;
            Long_dir = app.LongitudeDirectionDropDown.Value; %east or west
            Long_pol = 0;
            if(strcmp(Long_dir, 'East'))
                Long_pol = 1;
            end
            if(strcmp(Long_dir, 'West'))
                Long_pol=-1;
            end
            %User defined function
            LONG = Longitude(Long_deg,Long_min,Long_sec,Long_pol) %conversion to degrees

            %--GreenwichMeanTime--
             GMT = app.GMTEditField.Value;
             if(GMT >0)
                 GMT= GMT*(-1);
             end
             if(GMT<=0)
                 GMT= GMT*(1);
             end

            %-----------------InitiateSerialCommunication------------------
            ar = serial('/dev/cu.usbmodem1421','BaudRate',9600);% serial Object
            fopen(ar); %Open serial port
            l=0;            
            while (l<2) %number of different objects to track before calibration is needed
                app.RecalibrateButton.Visible='on'; %set calibration button visible
                %-----------------------UserInput-------------------------
                %-----------------LocationOfCelestialObject------------------
                Flag1=0;
                while Flag1==0;
                    prompt = {'Enter RA Hours:','Enter RA Minutes:','Enter RA Seconds:' ,'Enter DEC Degrees:','Enter DEC Minutes:','Enter DEC Seconds:','Enter Rise Time Hour:','Enter Rise Time Minute:'};
                    dlgtitle = 'Celestial Object Coordinates';
                    dims = [1 50];
                    answer = inputdlg(prompt,dlgtitle,dims)
                    if(isempty(answer)) %prevents program malfunction if no input is given
                        fclose(ar); % close serial port
                        return;
                    else
                        %--Right Ascension Angle--
                        RA_hour = str2num(answer{1});
                        RA_min = str2num(answer{2});
                        RA_sec = str2num(answer{3});
                        %User defined function
                        RA = RightAscension(RA_hour,RA_min,RA_sec); %conversion to degrees                        
                        
                        %--Declination Angle--
                        DEC_deg = str2num(answer{4});
                        DEC_min = str2num(answer{5});
                        DEC_sec = str2num(answer{6});
                        %User defined function
                        DEC = Declination(DEC_deg,DEC_min,DEC_sec); %conversion to degrees
                        
                        %Validity Checks
                        if(RA<0 || RA>360)
                            h=msgbox('ERROR. Enter valid RA value (0-24 hours)');
                            pause(5);
                            delete(h);
                        elseif(DEC<-90 || DEC>90)
                            h=msgbox('ERROR. Declination must range between -90 and 90');
                            pause(5);
                            delete(h);
                        else    
                            Flag1 = 1;
                        end
                        
                        %--Rise time of celestial object
                        RiseH=str2num(answer{7});
                        RiseM=str2num(answer{8});
                        riseup=RiseH+(RiseM/60);
                    end
                end
                %--------------Local Sidereal Time-----------------
                Flag2 = 0;
                while(Flag2==0)
                prompt = {'Enter Year: (yyyy)','Enter Month: (1-12)','Enter Day: (1-31)','Enter Starting Hour: (1-24)','Enter Starting Minute: (0-60)','Enter Ending Hour: (1-24)','Enter Ending Minute: (0-60)'};
                dlgtitle = 'Track Date and Time Details ';
                dims = [1 50];
                answer = inputdlg(prompt,dlgtitle,dims)
                if(isempty(answer))
                    fclose(ar); % close serial port
                    return;
                end
                Year = str2num(answer{1});
                Month = str2num(answer{2});
                Day = str2num(answer{3});
                Hour1 = str2num(answer{4});
                Min1 = str2num(answer{5});
                Hour2 = str2num(answer{6});
                Min2 = str2num(answer{7});
                
                Hour1 = Hour1 + (Min1/60);
                Hour2 = Hour2 + (Min2/60);
                
                if(abs((Hour2-Hour1))>12)
                            y=msgbox('ERROR. Please limit the tracking time to 12 hours');
                            pause(5);
                            delete(y);
                else
                    Flag2=1;
                end
                
                end
                %-----------------LocalSiderealTime1------------------
                %User defined function
                LST = LocalSiderealTime(Year,Month,Day,Hour1,Min1,GMT,LONG);  
                                
                %--HourAngleCalculation--
                HA = LST -RA; %in degrees
                                
                %-----------------LocalSiderealTime2------------------
                %User defined function
                LST2 = LocalSiderealTime(2020,Month,Day,Hour2,Min2,GMT,LONG);
                
                %--HourAngleOfEnding--
                HA2 = LST2 -RA;%in degrees
                
                %-----------------------UserInput-------------------------
                %-----------------------SpeedInput-------------------------
                list = {'Slow','Medium','Fast'};
                [indx,tf] = listdlg('PromptString',{'Select the desired speed of track:'},'ListString',list,'SelectionMode','single','ListSize',[200,90]);               
                if(tf==0)
                    fclose(ar); % close serial port
                    return;
                end
                if(indx==1)
                    speed=1;
                end
                if(indx==2)
                    speed=100;
                end
                if(indx==3)
                    speed=1000;
                end
                                
                %--CompensationForCrossing0or360--
                p=0;%counter for negative values
                HA_diff = HA2-HA; %difference in hour angles
                HourDiff=Hour2-Hour1; %difference in time
                diffi=0;
                riseup=riseup+1; %rise time consideration
                if(Hour1>riseup) %check starting hour
                    diffi = (Hour1*15)-(riseup*15)
                    RA=RA+diffi;
                end
                if(Hour1<riseup)%check starting hour
                    diffi = (riseup*15)-(Hour1*15)
                    RA=RA-diffi;
                end               
                RA_HA=RA+HA_diff;
                range=(HourDiff*60);%minute
                RA_shift= linspace(RA,RA_HA,range); %right ascension vector
                HA_past = linspace(HA,HA2,range); %Hour angle vector
                length(HA_past);
                for i=1:length(HA_past)%count all the negative values of HA
                    if(HA_past(i)<0)
                        p=p+1;
                    end
                end
                DEC = ones(1,range).*DEC
                %--ConvertingHAandDECtoALTandAZ--
                %User defined function
                [ALT2,Az2]=AltAz(HA_past,DEC,LAT); %Altitude and Azimuth Vectors
                
                %--Account for azimuth backtracking--
                for i=1:(length(Az2)-p)
                    Az2(p+i) = Az2(p+i)*-1; %Stepping path for motors
                end
                                
                %-----------------SpeedControl------------------
                %set step delays between motors
                scale = speed;
                delayAz1= ones(1,range).*((10)/scale)
                delayAz1(1) = 1e-6;
                delayALT1=ones(1,range).*((10)/scale)
                delayALT1(1)= 1e-6;
                delayHA1=ones(1,range).*((10)/scale)
                delayHA1(1)=5e-5;
                delayDEC1=ones(1,range).*((10)/scale)
                delayDEC1(1)=5e-5;
                                
                %----------------------------CommunicateWithMounts-------------------------------------------
                limSwitch = 360;                
                delswitch = 5e-4;
                Mr = csvread('counter');
                cnt = range;
                u=1;
                Azi=Az2;%AzimuthVector
                ALTi=ALT2;%AltitudeVector
                HAi=RA_shift;%RA axis Vector
                Deci=DEC;%Declination Vector
                
                %--Display what the system is currently doing--
                while(u<=cnt)
                    if(u==2)
                        z=msgbox('Starting Track');
                        pause(2);                        
                    end
                    if(u==1)
                       v=msgbox('Moving to starting position');
                       z=0;                       
                    else
                        delete(v);
                        delete(z);
                     end
                    
                    app.RecalibrateButton.Visible='off';
                    %Step through vectors
                    DEC=Deci(u);
                    Az=Azi(u);
                    ALT=ALTi(u);                   
                    HA=HAi(u);   
                    %Micro-step configurations
                    step = 4800/360;%Alt-Az mount
                    step2 = 800/360;%EQ mount
                    %Respective Delay Vectors
                    delayAz=delayAz1(u);
                    delayALT=delayALT1(u);
                    delayHA=delayHA1(u);
                    delayDEC=delayDEC1(u);
                    Mr = csvread('counter');%counter values
                    
                    %-------------------Set up Direction Pins & Steps for Azimuth----------------------
                    %--Azimuth--
                    %User defined function
                    [Az,Az_dir]=AzStepDir(Mr(1),Az,step);
                    
                    %--------------------Set Up direction pins & Steps for Hour Angle -------------------------------
                    %--HA--
                    %User defined function
                    [HA,HA_dir]=RAStepDir(Mr(5),HA,step2);
                    
                    %--------------------Set Up direction pins & steps for Altitude-------------------------------
                    %--Altitude--
                    %User defined function
                    [ALT,ALT_dir]=AltStepDir(Mr(3),ALT,step);
                    
                    %--------------------Set Up direction pins & steps for Declination-------------------------------
                    %--Declination--
                    %User defined function
                    [DEC,DEC_dir]=DecStepDir(Mr(7),DEC,step2);
                    
                    %--------------------------------Set up Counters for each motor---------------------
                    %User defined function
                    [zplace,negz,lplace,negl,HAplace,negHA,Dplace,negD]=CounterPosition(Az_dir,ALT_dir,HA_dir,DEC_dir);
                    %--------------------------------------------------------------------------------
                    
                    %--------------------------COMMUNICATE WITH THE MOUNTS---------------------------
                    %------------------------------------LimitSwitches----------------------------------
                    testAz= ((Azi(length(Azi)))*step);
                    testRA= ((HAi(length(HAi)))*step2);
                    
                    if((testAz>(limSwitch*step)) && check==0)
                       
                        for g=0:round(360*step)
                            if(Az_dir==9)
                                Stepper(ar,1,10,delswitch);%step azimuth motor(User defined function)                              
                                Mr(zplace) = Mr(zplace)-1;
                                Mr(negz)= Mr(negz)+1;
                            end
                            if(Az_dir==10)
                                Stepper(ar,1,9,delswitch);%step azimuth motor(User defined function)                                 
                                Mr(zplace) = Mr(zplace)-1;
                                Mr(negz)= Mr(negz)+1;
                            end
                        end
                        u=1;
                        Azi=Azi-360;%reset vector values to an anti-rotation of 360 degrees                     
                        Az=Azi(u);%start looping from beginning of the vector
                        [Az,Az_dir]=AzStepDir(Mr(1),Az,step);%set new direction counters (User defined function)                        
                        if(Az_dir==10)%clockwise
                            zplace=1;
                            negz = 2;
                        end
                        if(Az_dir==9)%anti-clockwise
                            zplace=2;
                            negz=1;
                        end
                    end
                    
                    if((testRA>(540*step2)) && check==0)
                        for g=0:round(360*step2)
                            if(HA_dir==25)
                                Stepper(ar,3,26,delswitch);%step RA motor(User defined function)                                 
                                Mr(HAplace) = Mr(HAplace)-1;
                                Mr(negHA)= Mr(negHA)+1;
                            end
                            if((HA_dir==26))
                                Stepper(ar,3,25,delswitch);%step RA motor(User defined function)                                  
                                Mr(HAplace) = Mr(HAplace)-1;
                                Mr(negHA)= Mr(negHA)+1;
                            end
                        end
                        u=1;
                        HAi=HAi-360; %reset vector values to an anti-rotation of 360 degrees
                        HA=HAi(u);%start looping from beginning of the vector
                        [HA,HA_dir]=RAStepDir(Mr(5),HA,step2);%set new direction counters (User defined function)                         
                        if(HA_dir==26)%clockwise
                            HAplace=5;
                            negHA=6;
                        end
                        if(HA_dir==25) %anti-clockwise
                            HAplace=6;
                            negHA=5;
                        end
                        
                    end
                    %-------------------------------LimitTrialEnd------------------------------------
                    %------------------------------Main Loop-----------------------------------
                    maxAngle1 = max(Az,ALT);
                    maxAngle2 = max(HA,DEC);
                    maxAngle=max((maxAngle1*step),(maxAngle2*step2));
                    
                    j=1;
                    while(j<=abs(maxAngle))
                        %---AzimuthMotor---
                        if(j<=(Az*(step)))
                            Stepper(ar,1,Az_dir,delayAz);                                                       
                            Mr(zplace) = Mr(zplace)+1;
                            Mr(negz) = Mr(negz)-1;
                        end
                        %---AltitudeMotor---
                        if(j<=(ALT*(step)))
                            Stepper(ar,2,ALT_dir,delayALT);                            
                            Mr(lplace) = Mr(lplace)+1;
                            Mr(negl) = Mr(negl)-1;
                        end
                        
                        %---HAMotor---
                        if(j<=(HA*(step2)))
                            Stepper(ar,3,HA_dir,delayHA);                            
                            Mr(HAplace) = Mr(HAplace)+1;
                            Mr(negHA) = Mr(negHA)-1;
                        end
                        %---DeclinationMotor---
                        if(j<=(DEC*(step2)))                           
                            Stepper(ar,4,DEC_dir,delayDEC);                        
                            Mr(Dplace) = Mr(Dplace)+1;
                            Mr(negD) = Mr(negD)-1;                            
                        end
                        j=j+1;
                        %-------DisplayAnglesOfRotation----- 
                        Azsign=Mr(1);
                        if(Azsign<0)
                            Azsign= Azsign+(360*step);
                        end                        
                        app.AzimuthEditField.Value = (Azsign)/(step);
                        app.AltitudeEditField.Value=(Mr(3))/(step);
                        app.RightAscensionEditField.Value = (Mr(5))/(step2);
                        app.DeclinationEditField.Value = (Mr(7))/(step2);
                    end
                    csvwrite('counter',Mr);%write counters to csv file
                    if(1<u && u<cnt)
                        delete(z);
                    end
                    u=u+1;   
                    
                end                                
                l=l+1;        
                csvwrite('counter',Mr);%store counter values
                EEPROM(ar,Mr(1),Mr(3),Mr(5),Mr(7)); %store last know directions on Arduino EEPROM                
            end
            fclose(ar); % close serial port
            app.RecalibrateButton.Visible='on';            
        end

        % Button pushed function: ConfirmLocationButton
        function ConfirmLocationButtonPushed(app, event)
            %Verify that the user input is valid
            LAT_deg = app.LatitudeDegreesEditField.Value;
            LAT_min = app.LatitudeMinutesEditField.Value;
            LAT_sec = app.LatitudeSecondsEditField.Value;
            LAT_dir = app.LatitudeDirectionDropDown.Value; %north or south
            LAT_pol =0;
            if(strcmp(LAT_dir,'North'))
                LAT_pol = 1;
            end
            if(strcmp(LAT_dir, 'South'))
                LAT_pol=-1;
            end
            LAT = Latitude(LAT_deg,LAT_min,LAT_sec,LAT_pol);
            
            Long_deg = app.LongitudeDegreesEditField.Value;
            Long_min = app.LongitudeMinutesEditField.Value;
            Long_sec = app.LongitudeSecondsEditField.Value;
            Long_dir = app.LongitudeDirectionDropDown.Value; %east or west
            Long_pol = 0;
            if(strcmp(Long_dir, 'East'))
                Long_pol = 1;
            end
            if(strcmp(Long_dir, 'West'))
                Long_pol=-1;
            end
            LONG = Longitude(Long_deg,Long_min,Long_sec,Long_pol);
            
            if( LAT<-90 || LAT>90)
                msgbox('ERROR. Latitude must range between -90 and 90');
            elseif( LONG<-180 || LONG>180)
                 msgbox('ERROR. Longitude must range between -180 and 180');
            else 
            Mr = [0,0,0,0,0,0,0,0];  %This button sets counter to 0
            csvwrite('counter',Mr);
            app.FindObjectButton.Visible='on';%set Find Object Button visible
               
            end


        end

        % Button pushed function: RecalibrateButton
        function RecalibrateButtonPushed(app, event)
            Mr = [0,0,0,0,0,0,0,0];%set counter values to zero
            csvwrite('counter',Mr);%write counter values to file
            %Set displays to zero
            app.AzimuthEditField.Value = 0;
            app.AltitudeEditField.Value=0;
            app.RightAscensionEditField.Value = 0;
            app.DeclinationEditField.Value = 0;
            %open serial port to initiate calibration routine
            ar = serial('/dev/cu.usbmodem1421','BaudRate',9600);% serial Object
            fopen(ar);%Open serial port
            pause(2);
            fclose(ar); % close serial port
        end

        % Value changed function: GMTEditField
        function GMTEditFieldValueChanged(app, event)
                   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploreTheGalaxyUIFigure = uifigure;
            app.ExploreTheGalaxyUIFigure.Name = 'Explore The Galaxy';
            app.FindObjectButton = uibutton(app.ExploreTheGalaxyUIFigure, 'push');
            app.FindObjectButton.ButtonPushedFcn = createCallbackFcn(app, @FindObjectButtonPushed, true);
            app.FindObjectButton.Icon = 'galaxyy.jpg';
            app.FindObjectButton.Visible = 'off';
            app.FindObjectButton.Text = 'Find Object';
            app.ConfirmLocationButton = uibutton(app.ExploreTheGalaxyUIFigure, 'push');
            app.ConfirmLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmLocationButtonPushed, true);
            app.ConfirmLocationButton.Text = 'Confirm Location';
            app.MountAnglesDisplayPanel = uipanel(app.ExploreTheGalaxyUIFigure);
            app.MountAnglesDisplayPanel.Title = 'Mount Angles Display';
            app.AltazimuthMovementLabel = uilabel(app.MountAnglesDisplayPanel);
            app.AltazimuthMovementLabel.Text = 'Altazimuth Movement';
            app.AzimuthEditFieldLabel = uilabel(app.MountAnglesDisplayPanel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.MountAnglesDisplayPanel, 'numeric');
            app.AltitudeEditFieldLabel = uilabel(app.MountAnglesDisplayPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.MountAnglesDisplayPanel, 'numeric');
            app.EquatorialMovementLabel = uilabel(app.MountAnglesDisplayPanel);
            app.EquatorialMovementLabel.Text = 'Equatorial Movement';
            app.RightAscensionEditFieldLabel = uilabel(app.MountAnglesDisplayPanel);
            app.RightAscensionEditFieldLabel.Text = 'Right Ascension';
            app.RightAscensionEditField = uieditfield(app.MountAnglesDisplayPanel, 'numeric');
            app.DeclinationEditFieldLabel = uilabel(app.MountAnglesDisplayPanel);
            app.DeclinationEditFieldLabel.Text = 'Declination';
            app.DeclinationEditField = uieditfield(app.MountAnglesDisplayPanel, 'numeric');
            app.InputyourcurrentlocationPanel = uipanel(app.ExploreTheGalaxyUIFigure);
            app.InputyourcurrentlocationPanel.Title = 'Input your current location';
            app.LatitudeLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LatitudeLabel.Text = 'Latitude';
            app.LatitudeDegreesEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LatitudeDegreesEditFieldLabel.Text = 'Latitude Degrees';
            app.LatitudeDegreesEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LatitudeMinutesEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LatitudeMinutesEditFieldLabel.Text = 'Latitude Minutes';
            app.LatitudeMinutesEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LatitudeDirectionDropDownLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LatitudeDirectionDropDownLabel.Text = 'Latitude Direction';
            app.LatitudeDirectionDropDown = uidropdown(app.InputyourcurrentlocationPanel);
            app.LatitudeDirectionDropDown.Items = {'North', 'South'};
            app.LatitudeDirectionDropDown.Value = 'North';
            app.LatitudeSecondsEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LatitudeSecondsEditFieldLabel.Text = 'Latitude Seconds';
            app.LatitudeSecondsEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LongitudeLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LongitudeLabel.Text = 'Longitude';
            app.LongitudeDegreesEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LongitudeDegreesEditFieldLabel.Text = 'Longitude Degrees';
            app.LongitudeDegreesEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LongitudeMinutesEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LongitudeMinutesEditFieldLabel.Text = 'Longitude Minutes';
            app.LongitudeMinutesEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LongitudeSecondsEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LongitudeSecondsEditFieldLabel.Text = 'Longitude Seconds';
            app.LongitudeSecondsEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.LongitudeDirectionDropDownLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.LongitudeDirectionDropDownLabel.Text = 'Longitude Direction';
            app.LongitudeDirectionDropDown = uidropdown(app.InputyourcurrentlocationPanel);
            app.LongitudeDirectionDropDown.Items = {'East', 'West'};
            app.LongitudeDirectionDropDown.Value = 'East';
            app.GMTEditFieldLabel = uilabel(app.InputyourcurrentlocationPanel);
            app.GMTEditFieldLabel.Text = 'GMT';
            app.GMTEditField = uieditfield(app.InputyourcurrentlocationPanel, 'numeric');
            app.GMTEditField.ValueChangedFcn = createCallbackFcn(app, @GMTEditFieldValueChanged, true);
            app.CelestialObjectFinderLabel = uilabel(app.ExploreTheGalaxyUIFigure);
            app.CelestialObjectFinderLabel.Text = 'Celestial Object Finder';
            app.RecalibrateButton = uibutton(app.ExploreTheGalaxyUIFigure, 'push');
            app.RecalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalibrateButtonPushed, true);
            app.RecalibrateButton.Visible = 'off';
            app.RecalibrateButton.Text = 'Recalibrate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TelescopeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploreTheGalaxyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploreTheGalaxyUIFigure)
        end
    end
end